classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        StatusLabel    matlab.ui.control.Label
        ConnectButton  matlab.ui.control.Button
        PortDropDown   matlab.ui.control.DropDown
        Label          matlab.ui.control.Label
        SyncLamp       matlab.ui.control.Lamp
        GPIOLampLabel  matlab.ui.control.Label
        StopButton     matlab.ui.control.Button
        StartButton    matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Device      % 硬件通信适配器实例
        ForceLines  % 存储 10 条 AnimatedLine 的数组
        DataBuffer  % 真实数据缓存
        StartTime   % 记录实验开始的相对时间
    end
    
    methods (Access = private)
        
        function handleIncomingData(app, data)
            app.DataBuffer = [app.DataBuffer; data];
            
            if app.StartTime == -1
                app.StartTime = data(1);
            end
            currentTime = (data(1) - app.StartTime) / 1000; 
            
            forces = data(3:12);
            for i = 1:10
                addpoints(app.ForceLines(i), currentTime, forces(i));
            end
            
            if currentTime > 10
                app.UIAxes.XLim = [currentTime - 10, currentTime];
            end
            
            drawnow limitrate;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 扫描串口
            ports = serialportlist("available");
            if isempty(ports)
                app.PortDropDown.Items = {'无可用串口'};
                app.StatusLabel.Text = '状态: 未检测到设备';
            else
                app.PortDropDown.Items = ports;
                app.StatusLabel.Text = '状态: 等待连接';
            end
            
            % 初始化图表
            app.UIAxes.XLim = [0, 10]; 
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on';
            
            % 预分配波形线
            colors = lines(10);
            app.ForceLines = gobjects(1, 10);
            for i = 1:10
                app.ForceLines(i) = animatedline(app.UIAxes, 'Color', colors(i,:), 'LineWidth', 1.2);
            end
            
            app.DataBuffer = [];
            app.SyncLamp.Color = [0.8 0.8 0.8];
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed2(app, event)
            if isempty(app.Device), return; end
            
            app.Device.StopRecording();
            app.SyncLamp.Color = [0.8 0.8 0.8];
            app.StatusLabel.Text = '状态: 停止，正在保存数据';
            
            if ~isempty(app.DataBuffer)
                filename = sprintf('Experiment_%s.csv', datestr(now, 'yyyymmdd_HHMMSS'));
                T = array2table(app.DataBuffer, 'VariableNames', ...
                    {'Timestamp_ms', 'Angle', 'L1','L2','L3','L4','L5','R1','R2','R3','R4','R5'});
                writetable(T, filename);
                app.StatusLabel.Text = ['状态: 数据已存至 ', filename];
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if strcmp(app.ConnectButton.Text, '连接设备')
                port = app.PortDropDown.Value;
                if strcmp(port, '无可用串口'), return; end
                
                try
                    app.Device = SerialDevice(port, 115200);
                    % 绑定底层数据驱动到自定义处理函数
                    app.Device.DataCallback = @app.handleIncomingData;
                    
                    app.ConnectButton.Text = '断开连接';
                    app.StatusLabel.Text = ['状态: 已连接至 ', char(port)];
                    app.PortDropDown.Enable = 'off';
                catch ME
                    uialert(app.UIFigure, ME.message, '连接失败');
                end
            else
                delete(app.Device);
                app.Device = [];
                app.ConnectButton.Text = '连接设备';
                app.StatusLabel.Text = '状态: 已断开';
                app.PortDropDown.Enable = 'on';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isempty(app.Device), return; end
            
            app.DataBuffer = [];
            for i = 1:10, clearpoints(app.ForceLines(i)); end
            app.StartTime = -1;
            app.UIAxes.XLim = [0, 10];
            
            app.Device.StartRecording();
            app.SyncLamp.Color = 'green';
            app.StatusLabel.Text = '状态: 正在采集...';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '10路传感器实时波形')
            xlabel(app.UIAxes, '时间(s)')
            ylabel(app.UIAxes, '读取值')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'StartButton';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed2, true);
            app.StopButton.Text = 'StopButton';
            app.GPIOLampLabel = uilabel(app.UIFigure);
            app.GPIOLampLabel.Text = 'GPIO同步信号';
            app.SyncLamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '串口号';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = '连接设备';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = '状态：等待连接...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end